classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Button                    matlab.ui.control.Button
        AngleEditFieldLabel       matlab.ui.control.Label
        AngleEditField            matlab.ui.control.NumericEditField
        ChooseRotatingAngleLabel  matlab.ui.control.Label
        SliderLabel               matlab.ui.control.Label
        Slider                    matlab.ui.control.Slider
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            load position.mat
            
            x = position(:,1);
            y = position(:,2);
            plot(app.UIAxes,x,y,'.')
            
            
        end

        % Callback function
        function SliderValueChanged(app, event)
            
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            theta = app.AngleEditField.Value;  
            theta1 = theta*pi/180;
            load position.mat
            v = position';
            % choose a point which will be the center of rotation
            x_center = (max(v(1,:))+min(v(1,:)))/2;
            y_center = (max(v(2,:))+min(v(2,:)))/2;
            % create a matrix which will be used later in calculations
            center = repmat([x_center; y_center], 1, length(v));
            % define a 60 degree counter-clockwise rotation matrix
            R = [cos(theta1) -sin(theta1); sin(theta1) cos(theta1)];
            % do the rotation...
            s = v - center;     % shift points in the plane so that the center of rotation is at the origin
            so = R*s;           % apply the rotation about the origin
            vo = so + center;   % shift again so the origin goes back to the desired center of rotation
            % this can be done in one line as:
            % vo = R*(v - center) + center
            % pick out the vectors of rotated x- and y-data
            x_rotated = vo(1,:);
            y_rotated = vo(2,:);
            % make a plot
            plot(app.UIAxes,v(1,:), v(2,:), 'k.', x_rotated, y_rotated, 'r.');
            
        end

        % Value changed function: Slider
        function SliderValueChanged2(app, event)
            theta = app.Slider.Value;
            theta1 = theta*pi/180;
            load position.mat
            v = position';
            % choose a point which will be the center of rotation
            x_center = (max(v(1,:))+min(v(1,:)))/2;
            y_center = (max(v(2,:))+min(v(2,:)))/2;
            % create a matrix which will be used later in calculations
            center = repmat([x_center; y_center], 1, length(v));
            % define a 60 degree counter-clockwise rotation matrix
            R = [cos(theta1) -sin(theta1); sin(theta1) cos(theta1)];
            % do the rotation...
            s = v - center;     % shift points in the plane so that the center of rotation is at the origin
            so = R*s;           % apply the rotation about the origin
            vo = so + center;   % shift again so the origin goes back to the desired center of rotation
            % this can be done in one line as:
            % vo = R*(v - center) + center
            % pick out the vectors of rotated x- and y-data
            x_rotated = vo(1,:);
            y_rotated = vo(2,:);
            % make a plot
            plot(app.UIAxes,v(1,:), v(2,:), 'k.', x_rotated, y_rotated, 'r.');
     
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.AngleEditFieldLabel = uilabel(app.LeftPanel);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.ChooseRotatingAngleLabel = uilabel(app.LeftPanel);
            app.ChooseRotatingAngleLabel.Text = 'Choose Rotating Angle';
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [0 360];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged2, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end